classdef spidep < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        LabelTitulo              matlab.ui.control.Label
        TabGroup                 matlab.ui.container.TabGroup
        TabSelecciones           matlab.ui.container.Tab
        Image2                   matlab.ui.control.Image
        LabelInfo                matlab.ui.control.Label
        ButtonVisualizar         matlab.ui.control.Button
        ButtonGroupOpciones      matlab.ui.container.ButtonGroup
        LabelSolo4               matlab.ui.control.Label
        LabelPD                  matlab.ui.control.Label
        Button14                 matlab.ui.control.RadioButton
        Button13                 matlab.ui.control.RadioButton
        Button12                 matlab.ui.control.RadioButton
        Button11                 matlab.ui.control.RadioButton
        LabelTS                  matlab.ui.control.Label
        Label_2                  matlab.ui.control.Label
        Button6                  matlab.ui.control.RadioButton
        Button10                 matlab.ui.control.RadioButton
        Button9                  matlab.ui.control.RadioButton
        Button8                  matlab.ui.control.RadioButton
        Button7                  matlab.ui.control.RadioButton
        LabelTodas               matlab.ui.control.Label
        Spinner1                 matlab.ui.control.Spinner
        Button4                  matlab.ui.control.RadioButton
        Button3                  matlab.ui.control.RadioButton
        Button5                  matlab.ui.control.RadioButton
        Button2                  matlab.ui.control.RadioButton
        Button1                  matlab.ui.control.RadioButton
        TabImagen                matlab.ui.container.Tab
        ButtonVolverSelecciones  matlab.ui.control.Button
        ButtonDownloadImage      matlab.ui.control.Button
        Image                    matlab.ui.control.Image
    end

    
    properties (Access = private)
        opcion; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ButtonVisualizar
        function ButtonVisualizarPushed(app, event)
            %-----------
            %app.Image.Visible = 'on';
            %-----------
            if     app.Button1.Value,  app.opcion=1;
            elseif app.Button2.Value,  app.opcion=2;
            elseif app.Button3.Value,  app.opcion=3;
            elseif app.Button4.Value,  app.opcion=4;
            elseif app.Button5.Value,  app.opcion=5;
            elseif app.Button6.Value,  app.opcion=6;
            elseif app.Button7.Value,  app.opcion=7;
            elseif app.Button8.Value,  app.opcion=8;
            elseif app.Button9.Value,  app.opcion=9;
            elseif app.Button10.Value, app.opcion=10;
            elseif app.Button11.Value, app.opcion=11;
            elseif app.Button12.Value, app.opcion=12;
            elseif app.Button13.Value, app.opcion=13;
            elseif app.Button14.Value, app.opcion=14;
            else                     , app.opcion=1';
            end
            %-----------
            switch app.opcion
                case 1  
                    switch app.Spinner1.Value
                        case 1, app.Image.ImageSource='1-1.png';
                        case 2, app.Image.ImageSource='1-2.png';
                        case 3, app.Image.ImageSource='1-3.png';
                        case 4, app.Image.ImageSource='1-4.png';
                    end
                case 2,  app.Image.ImageSource='2.png';
                case 3,  app.Image.ImageSource='3.png';
                case 4,  app.Image.ImageSource='4.png';
                case 5,  app.Image.ImageSource='5.png';
                case 6,  app.Image.ImageSource='6.png';
                case 7,  app.Image.ImageSource='7.png';
                case 8,  app.Image.ImageSource='8.png';
                case 9,  app.Image.ImageSource='9.png';
                case 10, app.Image.ImageSource='10.png';
                case 11, app.Image.ImageSource='11.png';
                case 12, app.Image.ImageSource='12.png';
                case 13, app.Image.ImageSource='13.png';
                case 14, app.Image.ImageSource='14.png';
            end
            %-----------
            app.TabGroup.SelectedTab = app.TabImagen;
            %-----------
        end

        % Button pushed function: ButtonVolverSelecciones
        function ButtonVolverSeleccionesPushed(app, event)
            %-----------
            app.TabGroup.SelectedTab = app.TabSelecciones;
            %-----------
        end

        % Button pushed function: ButtonDownloadImage
        function ButtonDownloadImagePushed(app, event)
            %-----------
            if app.opcion == 1
                nombrearchivo = strcat(int2str(app.opcion),'-',int2str(app.Spinner1.Value),'-full.png');
            else
                nombrearchivo = strcat(int2str(app.opcion),'-full.png');
            end
            fullnamesource       = fullfile(pwd,nombrearchivo);
            [filename, pathname] = uiputfile(fullnamesource);
            fullnametarget       = fullfile(pathname,filename);
            if ~isfolder(fullnametarget)
                copyfile(fullnamesource,fullnametarget,"f");
            end
            %-----------
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabSelecciones = uitab(app.TabGroup);
            app.TabSelecciones.Title = 'Selecciones';
            app.ButtonGroupOpciones = uibuttongroup(app.TabSelecciones);
            app.ButtonGroupOpciones.Title = 'Opciones de visualización:';
            app.Button1 = uiradiobutton(app.ButtonGroupOpciones);
            app.Button1.Text = 'Muestras normalizadas para el paciente con ID:';
            app.Button1.Value = true;
            app.Button2 = uiradiobutton(app.ButtonGroupOpciones);
            app.Button2.Text = 'Muestras en escala de tiempo normalizada';
            app.Button5 = uiradiobutton(app.ButtonGroupOpciones);
            app.Button5.Text = 'Temperatura (plots superpuestos)';
            app.Button3 = uiradiobutton(app.ButtonGroupOpciones);
            app.Button3.Text = 'Temperatura U (plots superpuestos)';
            app.Button4 = uiradiobutton(app.ButtonGroupOpciones);
            app.Button4.Text = 'Temperatura U (plots consecutivos)';
            app.Spinner1 = uispinner(app.ButtonGroupOpciones);
            app.Spinner1.Limits = [1 4];
            app.Spinner1.Value = 1;
            app.LabelTodas = uilabel(app.ButtonGroupOpciones);
            app.LabelTodas.Text = 'Todas las muestras completas e interpoladas en escala de tiempo normalizada:';
            app.Button7 = uiradiobutton(app.ButtonGroupOpciones);
            app.Button7.Text = 'SPO2min y SPO2max';
            app.Button8 = uiradiobutton(app.ButtonGroupOpciones);
            app.Button8.Text = 'BPMmin, BPMmax y BPMavg';
            app.Button9 = uiradiobutton(app.ButtonGroupOpciones);
            app.Button9.Text = 'Sys y Dia';
            app.Button10 = uiradiobutton(app.ButtonGroupOpciones);
            app.Button10.Text = 'EDAmin, EDAmax y EDAavg';
            app.Button6 = uiradiobutton(app.ButtonGroupOpciones);
            app.Button6.Text = 'Temperatura';
            app.Label_2 = uilabel(app.ButtonGroupOpciones);
            app.Label_2.Text = 'Muestras originales en escala de tiempo normalizada:';
            app.LabelTS = uilabel(app.ButtonGroupOpciones);
            app.LabelTS.Text = 'Series temporales';
            app.Button11 = uiradiobutton(app.ButtonGroupOpciones);
            app.Button11.Text = 'IRA';
            app.Button12 = uiradiobutton(app.ButtonGroupOpciones);
            app.Button12.Text = 'ITU';
            app.Button13 = uiradiobutton(app.ButtonGroupOpciones);
            app.Button13.Text = 'PB';
            app.Button14 = uiradiobutton(app.ButtonGroupOpciones);
            app.Button14.Text = 'Temperatura en IRA';
            app.LabelPD = uilabel(app.ButtonGroupOpciones);
            app.LabelPD.Text = 'Procesado de datos';
            app.LabelSolo4 = uilabel(app.ButtonGroupOpciones);
            app.LabelSolo4.Text = '(solo 4 pacientes como demostración de los 60 registrados)';
            app.ButtonVisualizar = uibutton(app.TabSelecciones, 'push');
            app.ButtonVisualizar.ButtonPushedFcn = createCallbackFcn(app, @ButtonVisualizarPushed, true);
            app.ButtonVisualizar.Text = 'Procesar y Visualizar';
            app.LabelInfo = uilabel(app.TabSelecciones);
            app.LabelInfo.Text = {'Algunas enfermedades infecciosas pueden predecirse mediante algoritmos de Machine Learning analizando la evolución de ciertos parámetros médicos a lo largo del tiempo. En este estudio se han procesado y normalizado conjuntos de datos de tres enfermedades infeccionas (respiratoria aguda -IRA-, del tracto urinario -ITU- y de la piel y tejidos blandos -PB-) para su análisis con algoritmos de clasificación.'; ''; 'Las opciones señaladas a la izquierda permiten visualizar de distintas formas las series temporales de los datos, tas aplicar algortimos de análisis y normalización. Esto permite: (1) que puedan ser interpretados más fácilmente por parte del personal médico con propósitos diagnósticos; (2) que puedan ser procesados fácilmente por algoritmos de clasificación (Naive Bayes, Filtered Classifier y Random Forest) para detectar patrones de comportamiento y calcular probabilidades de desarrollar infecciones para unos datos de entrada particulares.'; ''; 'Los datos se ha obtenido mediante sensores colocados en pacientes de residencias de mayores, y procesan las siguientes variables médicas:'; ''; '  * T: Temperatura corporal.'; '  * SPO2: Saturación de oxígeno.'; '  * BPM: Pulso cardiáco.'; '  * Sys: Sístole.'; '  * Dias: Diástole.'; '  * EDA: Actividad electrodérmica.'};
            app.Image2 = uiimage(app.TabSelecciones);
            app.Image2.ImageSource = 'spidep.png';
            app.TabImagen = uitab(app.TabGroup);
            app.TabImagen.Title = 'Visualización de datos';
            app.Image = uiimage(app.TabImagen);
            app.Image.ImageSource = 'spidep.png';
            app.ButtonDownloadImage = uibutton(app.TabImagen, 'push');
            app.ButtonDownloadImage.ButtonPushedFcn = createCallbackFcn(app, @ButtonDownloadImagePushed, true);
            app.ButtonDownloadImage.Text = {'Descarga en'; 'alta resolución'};
            app.ButtonVolverSelecciones = uibutton(app.TabImagen, 'push');
            app.ButtonVolverSelecciones.ButtonPushedFcn = createCallbackFcn(app, @ButtonVolverSeleccionesPushed, true);
            app.ButtonVolverSelecciones.Text = 'Volver a Selecciones';
            app.LabelTitulo = uilabel(app.UIFigure);
            app.LabelTitulo.Text = 'SPIDEP-2: Predicción de enfermedades infecciosas mediante análisis de series temporales';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = spidep

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end